classdef mohrCircleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        PlotButton       matlab.ui.control.Button
        xyEditField      matlab.ui.control.NumericEditField
        xyLabel          matlab.ui.control.Label
        yEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.NumericEditField
        xEditFieldLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [sigma_x,sigma_y,tau_xy] = takeInput(app)
            % == Take inputs from user ==
            sigma_x = app.xEditField.Value;
            sigma_y = app.yEditField.Value;
            tau_xy = app.xyEditField.Value;  
        end
        
        function plotCircle(app,center_x,center_y, radius)
            % == Plotting a circle and marking extreme points ==
            theta = 0:0.01:2*pi;
            
            % Using parametric equation of circle
            x = center_x + radius*cos(theta); 
            y = center_y + radius*sin(theta);
            plot(app.UIAxes,x,y);
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            title(app.UIAxes,"\color{red}Mohr's Circle", 'FontSize', 20)
            xlabel(app.UIAxes,"\color{blue}Normal Stress, \sigma",'FontSize', 18);
            ylabel(app.UIAxes,"\color{blue}Shear Stress, \tau", 'FontSize', 18);
            
        end
        
        function connectAndMark(app, sigma_x, tau_xy, sigma_1, sigma_2, center_x, center_y, tau_max, tau_min)
            plot(app.UIAxes,[sigma_1 sigma_2], [0 0]) % connects sigma_1 and sigma_2 by
            % the x-values in one vector and the y-values in another vector
            plot(app.UIAxes,[center_x center_x],[tau_max tau_min], 'b:') % connects tau_max and tau_min
            % plot(center_x, center_y, 'g*'); % marks center of the circle
            text(app.UIAxes,center_x, center_y, "C(" + center_x + ", 0)", 'FontSize', 13); 
            text(app.UIAxes,sigma_1, 0, "\sigma_{1}(" + sigma_1 + ",0)" , 'FontSize', 14); 
            text(app.UIAxes,sigma_2, 0, "\sigma_{2}(" + sigma_2 + ",0)" , 'FontSize', 14);
            text(app.UIAxes,center_x, tau_max, "\tau_{max}(" + center_x + ", " + tau_max + ")", 'FontSize', 14); 
            text(app.UIAxes,center_x, tau_min, "\tau_{min}(" + center_x + ", " + tau_min + ")", 'FontSize', 14); 
            
            % Marking point, A
            text(app.UIAxes,sigma_x,tau_xy, "A(" + sigma_x + ", " + tau_xy + ")", 'FontSize', 14) ;
            plot(app.UIAxes,[center_x sigma_x], [0 tau_xy]);
            % slope_sigma = (0-tau_xy)/(sigma_1 - sigma_x);
            % if (sigma_x>0)
            %     x_sigma = center_x+1.2*radius;
            % else
            %     x_sigma = center_x-1.2*radius;
            % end
            % y_sigma = 0 + slope_sigma*(x_sigma-sigma_1);
            % plot([sigma_x x_sigma], [tau_xy y_sigma]);
            plot(app.UIAxes,[sigma_1 sigma_x], [0 tau_xy]);
            plot(app.UIAxes,[center_x sigma_x], [tau_min tau_xy], 'm');
            hold (app.UIAxes, 'off')
            axis(app.UIAxes, 'equal'); % Makes aspect ratio = 1 to avoid showing ellipse 
        end
    end
    
    
    methods (Access = private)
        
        function [radius, center_x, center_y, sigma_1, sigma_2, tau_max, tau_min] = calcValues(~, sigma_x, sigma_y,tau_xy) 
            % == Find radius and center point from input ==
            radius = sqrt(((sigma_x-sigma_y)/2)^2+(tau_xy)^2);
            center_x = (sigma_x+sigma_y)/2;
            center_y = 0;
            sigma_1 = center_x + radius;
            sigma_2 = center_x - radius;
            tau_max = radius;
            tau_min = -radius;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            [sigma_x,sigma_y,tau_xy] = takeInput(app); %takes input
                
            [radius, center_x, center_y, sigma_1, sigma_2, tau_max, tau_min] = calcValues(app, sigma_x, sigma_y, tau_xy); %Finds necessary values
            
            plotCircle(app,center_x,center_y, radius); %Plots the circle
            
            connectAndMark(app, sigma_x, tau_xy, sigma_1, sigma_2, center_x, center_y, tau_max, tau_min);
 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mohr''s Circle')
            xlabel(app.UIAxes, 'Normal Stress, \sigma')
            ylabel(app.UIAxes, 'SHear Stress, τ')
            zlabel(app.UIAxes, 'Z')
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Interpreter = 'latex';
            app.xEditFieldLabel.Text = 'σx';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'σy';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.xyLabel = uilabel(app.UIFigure);
            app.xyLabel.Text = 'τxy';
            app.xyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = 'scatter-plot.png';
            app.PlotButton.Text = 'Plot';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tooltip = {'Stress Block'};
            app.Image.ImageSource = 'stress block.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mohrCircleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end